classdef HW_I_Risk_Management_definitiva < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RESETASSIButton                matlab.ui.control.Button
        MostraDensiteVaRButton         matlab.ui.control.Button
        livelloVaREditField            matlab.ui.control.NumericEditField
        livelloVaREditFieldLabel       matlab.ui.control.Label
        MostraheatmapButton            matlab.ui.control.StateButton
        MostradiversepercentualidicallSwitch  matlab.ui.control.Switch
        MostradiversepercentualidicallSwitchLabel  matlab.ui.control.Label
        PercentualecallSlider          matlab.ui.control.Slider
        PercentualecallSliderLabel     matlab.ui.control.Label
        PercentualecoperturaSlider     matlab.ui.control.Slider
        PercentualecoperturaSliderLabel  matlab.ui.control.Label
        VolumeeffettivoEditField       matlab.ui.control.NumericEditField
        VolumeeffettivoEditFieldLabel  matlab.ui.control.Label
        MostraprofittiPortafoglioButton  matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostraprofittiPortafoglioButton
        function MostraprofittiPortafoglioButtonPushed(app, event)

            cla(app.UIAxes);

            %exchange rates di studio
            %potremmo prenderne di più ma ci danno già tutta l'informazione
            %questi 3 valori. 
            x=[1.01,1.22,1.48];

            %quanto copro in option e in forward
            %percentuale copertura massima=25000, basta cambiare il valore
            %per aumentarla o diminuirla
            cover=app.PercentualecoperturaSlider.Value/100;
            cover_o=25000*(cover*(app.PercentualecallSlider.Value/100));
            cover_f=25000*cover-cover_o;

            %%%%%%%%%%%%%COSTRUZIONE PORTAFOGLIO COMPLESSIVO%%%%%%%%%%%
            Ricavo=app.VolumeeffettivoEditField.Value*1.22*ones(1,length(x));
            costo_dovuto=app.VolumeeffettivoEditField.Value*x;
            
            %valore dato dai contratti forward ( e costo pari a zero )
            forward_value=cover_f*(x-1.22);
            
            %valore dato dai contratti option (costo dato da costo_option)
            strike_price=1.22;
            option_value=cover_o*max(0,(x-strike_price));
            costo_option=0.05*strike_price*cover_o; 
            
            %mettendo tutto insieme ottengo il valore del portafoglio
            %vettore sugli scenari di cambio di x

            Portfolio_value=Ricavo+forward_value+option_value-costo_option-costo_dovuto;

            %%%%%%%%%FINE COSTRUZIONE PORTAFOGLIO COMPLESSIVO%%%%%%%%%%

            
            plot(app.UIAxes,x,Portfolio_value,'-s','MarkerSize',10,'MarkerFaceColor','red')

            %flag dello switch per fare grafici multipli
            molteplice = app.MostradiversepercentualidicallSwitch.Value;

            if strcmp(molteplice, 'Si')
                hold(app.UIAxes);
            %frac ci dà le percentuali di option che vogliamo del totale coperto
            %cambiando frac e frac_complementare
            frac=[0,25,50,75,100]/100;
            frac_complementare=[100,75,50,25,0]/100; %frac_c=ones(length(frac),1)'-frac
            cover_o_d=25000*(frac*cover);
            cover_f_d=25000*(frac_complementare*cover);

            %Portfolio_value_d= zeros(length(frac),length(x));
            for i=1:length(frac)
                %costruisco valori forward e option per diverse percentuali
                %fissate
               forward_value_d=cover_f_d(i)*(x-1.22);
               option_value_d=max(0,cover_o_d(i)*(x-1.22));
               costo_option_d=0.05*strike_price*cover_o_d(i);
               
               Portfolio_value_d=Ricavo+forward_value_d+option_value_d-costo_option_d-costo_dovuto;
 
               plot(app.UIAxes,x,Portfolio_value_d,'-s','MarkerSize',5,'MarkerFaceColor','black');
                 
            end
            hold(app.UIAxes);
            end
            %%%%%%%%END GRAFICI MULTIPLI%%%%%%%%

             l = legend(app.UIAxes,'Parametri scelti','0% call','25% call','50% call','75% call','100% call');
             l.ItemHitFcn = @hitcallback_ex1;

             %%funzione che permette di nascondere il grafico cliccando sul
             %%suo nome nella legenda
function hitcallback_ex1(src,evnt)

if strcmp(evnt.Peer.Visible,'on')
    evnt.Peer.Visible = 'off';
else 
    evnt.Peer.Visible = 'on';
end

end

        end

        % Value changed function: PercentualecoperturaSlider
        function PercentualecoperturaSliderValueChanged(app, event)
            %cover = app.PercentualecoperturaSlider.Value;
        end

        % Value changed function: PercentualecallSlider
        function PercentualecallSliderValueChanged(app, event)
            %perc_cover_o = app.PercentualecallSlider.Value;
        end

        % Callback function
        function Plot2ButtonPushed(app, event)

        end

        % Value changed function: VolumeeffettivoEditField
        function VolumeeffettivoEditFieldValueChanged(app, event)
            %value = app.VolumeeffettivoEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: MostradiversepercentualidicallSwitch
        function MostradiversepercentualidicallSwitchValueChanged(app, event)
            %molteplice = app.MostradiversepercentualidicallSwitch.Value;
        end

        % Value changed function: MostraheatmapButton
        function MostraheatmapButtonValueChanged(app, event)
            
            value = app.MostraheatmapButton.Value;
                
            %costruiamo vettore dei rate x 
            z = linspace(1.01,1.22,7);
            y = linspace(1.22,1.48,7);
            x= [z(1:end-1),y];
            x = round(x,2);
            %quanto copro in option e in forward
            %percentuale copertura massima=25000, basta cambiare il valore
            %per aumentarla o diminuirla
            cover=app.PercentualecoperturaSlider.Value/100;
            cover_o=25000*(cover*(app.PercentualecallSlider.Value/100));
            cover_f=25000*cover-cover_o;

          
            %valore dato dai contratti forward ( e costo pari a zero )
            forward_value=cover_f*(x-1.22);
            
            %valore dato dai contratti option (costo dato da costo_option)
            strike_price=1.22;
            option_value=cover_o*max(0,(x-strike_price));
            costo_option=0.05*strike_price*cover_o; 

            %%%%COSTRUISCO VETTORE VOLUMI%%%%%%%%
            volume= app.VolumeeffettivoEditField.Value;
            vec1=linspace(volume-10000,volume-2000,5);
            vec2=linspace(volume+2000,volume+10000,5);
            vettore_volumi=[vec1,volume,vec2];

             
                 %vettore_volumi = linspace(app.VolumeeffettivoEditField.Value-5000, app.VolumeeffettivoEditField.Value + 5000,10);
                 Portfolio_value_heat=zeros(length(vettore_volumi),length(x));
                 for i=1:length(vettore_volumi)

                 Ricavo_heat(i,:)=vettore_volumi(i)*1.22*ones(1,length(x));
                 costo_dovuto_heat(i,:)=vettore_volumi(i)*x;
                 Portfolio_value_heat(length(vettore_volumi)-i+1,:)=Ricavo_heat(i,:)+forward_value+option_value-costo_option-costo_dovuto_heat(i,:);

                 end
                 %format BANK
                 Portfolio_value_heat = round(Portfolio_value_heat,10);
                 xlabels = x;
                 ylabels = vettore_volumi(end:-1:1);
                 heatmap(app.UIFigure,xlabels,ylabels,Portfolio_value_heat); 
        end

        % Value changed function: livelloVaREditField
        function livelloVaREditFieldValueChanged(app, event)
            %value = app.livelloVaREditField.Value;
        end

        % Button pushed function: MostraDensiteVaRButton
        function MostraDensiteVaRButtonPushed(app, event)
            cla(app.UIAxes3);
            %simulazione scenari dei rate x presi da una normale con media
            %1.22 e dev. std. 0.1;
            nScenari=100000;
            x = 1.22+0.1*randn(nScenari,1);
            %livello VaR scelto
            alpha = app.livelloVaREditField.Value;

%%%%%%%%% GENERAZIONE PORTAFOGLIO COME VISTO ANCHE IN PRECEDENZA %%%%%%%%%
            cover=app.PercentualecoperturaSlider.Value/100;
            cover_o=25000*(cover*(app.PercentualecallSlider.Value/100));
            cover_f=25000*cover-cover_o;
            volume = app.VolumeeffettivoEditField.Value;

            %%%costruzione Portafoglio complessivo%%%
            Ricavo=volume*1.22*ones(length(x),1);
            costo_dovuto=volume*x;
            
            %valore dato dai contratti forward ( e costo pari a zero )
            forward_value=cover_f*(x-1.22);
            
            %valore dato dai contratti option (costo dato da costo_option)
            strike_price=1.22;
            option_value=cover_o*max(0,(x-strike_price));
            costo_option=0.05*strike_price*cover_o*ones(length(x),1); 
            
            %mettendo tutto insieme ottengo il valore del portafoglio
            %vettore sugli scenari di cambio di x

            Portfolio_value=Ricavo+forward_value+option_value-costo_option-costo_dovuto;

%%%%%%%%% FINE GENERAZIONE PORTAFOGLIO %%%%%%%%%%%%%%%%%%%%%

            %per evitare errori dovuti ad epsilon di macchina
            %vedi caso: volume=25000; copertura=100%,full forward
          
            Portfolio_value = round(Portfolio_value,10);

            % ordiniamo il portafoglio per assegnarli delle probabilità
            % ordinate dal valore più grande al più piccolo che ci servira
            % nel while di dopo per calcolare il VaR
           Portfolio_value = sort(Portfolio_value, 'descend');
            %%%%%%%%%%%%%%%% MODO 1 %%%%%%%%%%%%%%%%%%%%%%


 prob = normpdf(Portfolio_value,mean(Portfolio_value),var(Portfolio_value));
 prob = prob/sum(prob);

%Calcolo del VaR sommando le probabilità fino alla prima
%che raggiunge o supera il livello alpha. 
%successivamente salvato l'indice lo prendiamo e troviamo il valore del
%portafoglio

somma = 0;
i=1;
while somma < alpha
    somma = prob(i) + somma;
    indice = i;
    i = i+1;
end
%%%%%%%%%%%%%%%%%%%%%% MODO 2 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
% x = sort (x,'descend');
% 
% %Calcolo del VaR sommando le probabilità fino alla prima
% %che raggiunge o supera il livello alpha. 
% %successivamente salvato l'indice lo prendiamo e troviamo il valore del
% %portafoglio
% 
% somma = 0;
% i=1;
% prob=0;
% 
% while somma < alpha
%     somma = prob + somma;
%     prob = normcdf(x(i),1.22,0.1)-normcdf(x(i+1),1.22,0.1);
%     indice = i;
%     i = i+1;
% end
%%%%%%%%%%%%%%%%%%%%%%%%%%%% END DIVERSI MODI %%%%%%%%%%%%%%%%%%%%%%%

VaR = Portfolio_value(indice);

hold(app.UIAxes3)
%generazione istogramma, verde=valori>0; rosso=valori<VaR; blu=Var<valori<0
h1= histogram(app.UIAxes3,Portfolio_value,100,'Normalization','probability');
h1.FaceColor = 'b';
edges = h1.BinEdges;

%verde = valori > 0;
counts = h1.Values.*(edges(1:end-1)>0);
h2 = histogram(app.UIAxes3,'BinEdges',edges,'BinCounts',counts);
h2.FaceColor = 'green';

%rosso = valori < VaR;
counts = h1.Values.*(edges(1:end-1) < VaR);
h3 = histogram(app.UIAxes3,'BinEdges',edges,'BinCounts',counts);
h3.FaceColor = 'r';


% inserisce VaR linea, e testo con il valore
plot(app.UIAxes3,[VaR;VaR],[0;max(h1.BinCounts)*0.80]/length(x),'--r');
text(app.UIAxes3,edges(1), max(h1.BinCounts)/length(x),['VaR = ' num2str(round(VaR,2))]);
hold(app.UIAxes3)
        end

        % Button pushed function: RESETASSIButton
        function RESETASSIButtonPushed(app, event)
            %pulisce i grafici
            cla(app.UIAxes);
            cla(app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Different call fracs')
            xlabel(app.UIAxes, 'Exchange Rate')
            ylabel(app.UIAxes, 'Profitto')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Valore Portafoglio al variare dei fattori di rischio')
            xlabel(app.UIAxes2, 'Exchange Rate')
            ylabel(app.UIAxes2, 'crescere del volume')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [1.01 1.04 1.08 1.11 1.15 1.19 1.22 1.26 1.31 1.35 1.39 1.44 1.48];
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Densità e VaR Portafoglio')
            xlabel(app.UIAxes3, 'Valore Portafoglio')
            ylabel(app.UIAxes3, 'Probabilità')
            zlabel(app.UIAxes3, 'Z')
            app.MostraprofittiPortafoglioButton = uibutton(app.UIFigure, 'push');
            app.MostraprofittiPortafoglioButton.ButtonPushedFcn = createCallbackFcn(app, @MostraprofittiPortafoglioButtonPushed, true);
            app.MostraprofittiPortafoglioButton.Text = 'Mostra profitti Portafoglio';
            app.VolumeeffettivoEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeeffettivoEditFieldLabel.Text = 'Volume effettivo';
            app.VolumeeffettivoEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeeffettivoEditField.ValueDisplayFormat = '%.0f';
            app.VolumeeffettivoEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeeffettivoEditFieldValueChanged, true);
            app.VolumeeffettivoEditField.Value = 25000;
            app.PercentualecoperturaSliderLabel = uilabel(app.UIFigure);
            app.PercentualecoperturaSliderLabel.Text = 'Percentuale copertura';
            app.PercentualecoperturaSlider = uislider(app.UIFigure);
            app.PercentualecoperturaSlider.ValueChangedFcn = createCallbackFcn(app, @PercentualecoperturaSliderValueChanged, true);
            app.PercentualecallSliderLabel = uilabel(app.UIFigure);
            app.PercentualecallSliderLabel.Text = 'Percentuale call';
            app.PercentualecallSlider = uislider(app.UIFigure);
            app.PercentualecallSlider.ValueChangedFcn = createCallbackFcn(app, @PercentualecallSliderValueChanged, true);
            app.MostradiversepercentualidicallSwitchLabel = uilabel(app.UIFigure);
            app.MostradiversepercentualidicallSwitchLabel.Text = 'Mostra diverse percentuali di call';
            app.MostradiversepercentualidicallSwitch = uiswitch(app.UIFigure, 'slider');
            app.MostradiversepercentualidicallSwitch.Items = {'Si', 'No'};
            app.MostradiversepercentualidicallSwitch.ValueChangedFcn = createCallbackFcn(app, @MostradiversepercentualidicallSwitchValueChanged, true);
            app.MostradiversepercentualidicallSwitch.Value = 'No';
            app.MostraheatmapButton = uibutton(app.UIFigure, 'state');
            app.MostraheatmapButton.ValueChangedFcn = createCallbackFcn(app, @MostraheatmapButtonValueChanged, true);
            app.MostraheatmapButton.Text = 'Mostra heatmap';
            app.livelloVaREditFieldLabel = uilabel(app.UIFigure);
            app.livelloVaREditFieldLabel.Text = 'livello VaR';
            app.livelloVaREditField = uieditfield(app.UIFigure, 'numeric');
            app.livelloVaREditField.ValueChangedFcn = createCallbackFcn(app, @livelloVaREditFieldValueChanged, true);
            app.livelloVaREditField.Value = 0.95;
            app.MostraDensiteVaRButton = uibutton(app.UIFigure, 'push');
            app.MostraDensiteVaRButton.ButtonPushedFcn = createCallbackFcn(app, @MostraDensiteVaRButtonPushed, true);
            app.MostraDensiteVaRButton.Text = 'Mostra Densità e VaR';
            app.RESETASSIButton = uibutton(app.UIFigure, 'push');
            app.RESETASSIButton.ButtonPushedFcn = createCallbackFcn(app, @RESETASSIButtonPushed, true);
            app.RESETASSIButton.Text = 'RESET ASSI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW_I_Risk_Management_definitiva

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end